classdef condensate_analyzer_confocal_COLOCAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DonotcopypasteorsharethiscodewithanyonewithoutpermissionLabel  matlab.ui.control.Label
        redch_tolerance_parameter     matlab.ui.control.NumericEditField
        redch_peak_background_fold    matlab.ui.control.NumericEditField
        redch_background_setting      matlab.ui.control.NumericEditField
        RedchLabel                    matlab.ui.control.Label
        GreenchLabel                  matlab.ui.control.Label
        peak_background_fold          matlab.ui.control.NumericEditField
        PeakbackgroundEditFieldLabel  matlab.ui.control.Label
        SummarizeButton               matlab.ui.control.Button
        ForCOLOCALUpdated250908byTaeGyunKimLabel  matlab.ui.control.Label
        tolerance_parameter           matlab.ui.control.NumericEditField
        ToleranceparameterLabel       matlab.ui.control.Label
        background_setting            matlab.ui.control.NumericEditField
        BackgroundsettingLabel        matlab.ui.control.Label
        ImageSaveButton               matlab.ui.control.Button
        ROISelectButton               matlab.ui.control.Button
        FileSelectButton              matlab.ui.control.Button
        FolderpathTextArea            matlab.ui.control.TextArea
        FolderpathTextAreaLabel       matlab.ui.control.Label
        PathselectButton              matlab.ui.control.Button
        FileNameListBox               matlab.ui.control.ListBox
        FileNameListBoxLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        image_ch_green % Description
        image_ch_red
        img_ch_green_data_collection
        img_ch_red_data_collection
        once_or_more =1; % Description
        f1
        f2
        f3
        f4
        f5% Description
        average_cond_int
        sum_cond_int
        dilute_cond_int
        cond_size
        Kp
        G_transfer
        % Description
        fname % Description
        cell_selc % Description
        num_cond % Description
        overlapped_colocal
        pearson_cor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PathselectButton
        function PathselectButtonPushed(app, event)
            %% Set path & File Search
            addpath(cd)
            fold_path= uigetdir ; % select path using UI
            cd(fold_path) % change current folder to selected path
            
            app.FolderpathTextArea.Value= fold_path;
            %  File search
            f_info=dir('*.tif') ; % find files with .tif extension
            fnames = {f_info.name} ; % get file names
           % fname_list = fnames ;
            app.FileNameListBox.Items= fnames;
            % Parameter INput
            windowSize = 100 ; 
            warning('off')
        end

        % Value changed function: FolderpathTextArea
        function FolderpathTextAreaValueChanged(app, event)
            value = app.FolderpathTextArea.Value;
            
        end

        % Button pushed function: FileSelectButton
        function FileSelectButtonPushed(app, event)
            fname=app.FileNameListBox.Value;
            app.fname = fname(1:end-4);
            dat=imread(fname) ;
            
            img_ch_red = dat(:,:,1); % 532, 640 laser ex) Cy5, mCherry
            img_ch_green=dat(:,:,2); % 488 laser ex ) eGFP
            img_ch_blue = dat(:,:,3); %DAPI
          
            figure(1)
            subplot(1,2,2) ;
            imshow(img_ch_red) ; colormap(jet);
            title('red channel')
      
            subplot(1,2,1) ;
            imagesc(img_ch_green); colormap(jet) ;
            title('Green channel')
            
            
            app.image_ch_green = img_ch_green;
            app.image_ch_red = img_ch_red ;
            
            
        end

        % Button pushed function: ROISelectButton
        function ROISelectButtonPushed(app, event)
            %%
            flag= app.once_or_more;
            if flag ==1
                
            else
                try
                    close (app.f2)
                    close (app.f3)
                    close (app.f4)
                    close (app.f5)
                    close (figure(3),figure(4))
                    %clear
                catch
                end
                
                
            end
            f1=figure(1);
            h = drawassisted;
            h_pos = h.Position ;
            
            mask = createMask(h);
            img_ch_green = app.image_ch_green ;
            img_ch_red = app.image_ch_red ;
            
            background_setting = app.background_setting.Value;
            peak_background_fold = app.peak_background_fold.Value;
            tolerance_parameter = app.tolerance_parameter.Value ;
            
            redch_background_setting = app.redch_background_setting.Value;
            redch_peak_background_fold = app.redch_peak_background_fold.Value;
            redch_tolerance_parameter = app.redch_tolerance_parameter.Value ;
            
            fig_num = 0;
            fig_num2 = 1;
            [cond_img_green, img_ch_green_data_collection]=condensate_analyzer_confocal_FUNCTION(mask,h_pos,img_ch_green,background_setting, peak_background_fold,tolerance_parameter,fig_num,'Green channel');
            [cond_img_red, img_ch_red_data_collection]=condensate_analyzer_confocal_FUNCTION(mask,h_pos,img_ch_red,redch_background_setting, redch_peak_background_fold,redch_tolerance_parameter,fig_num2,'Red channel') ;
            
            img_ch_green_data_collection 
            app.img_ch_green_data_collection = img_ch_green_data_collection ;
            app.img_ch_red_data_collection = img_ch_red_data_collection ;
            
            %% Colocalization - ver 1 : overlapped cluster            
            cell_green =img_ch_green_data_collection.cell_selc;
            cell_red = img_ch_red_data_collection.cell_selc;
            R = corrcoef(cell_green, cell_red);
            app.pearson_cor = R(2);
            
            
            %% Colocalization - ver 2 : overlapped cluster
            matrix1 = cond_img_green ; matrix1(cond_img_green>0)=1;
            matrix2= cond_img_red ; matrix2(cond_img_red>0) =1 ;
            
            % Label clusters in each matrix
            [labeledMatrix1, numClusters1] = bwlabel(matrix1);
            [labeledMatrix2, ~] = bwlabel(matrix2);
            
            overlapCount = 0; % variable to store the number of overlapping clusters
            
            % For each cluster in matrix1
            for i = 1:numClusters1
                currentCluster = (labeledMatrix1 == i); % select current cluster
                
                % Overlapping region with matrix2
                overlapWithMatrix2 = currentCluster & labeledMatrix2;
                
                % If the overlap area is greater than zero (e.g., >= 1 pixel)
                if sum(overlapWithMatrix2(:)) / sum(currentCluster(:)) ~=0
                    overlapCount = overlapCount + 1;
                end
            end
            %
            app.overlapped_colocal = overlapCount./numClusters1;
            
            
            app.once_or_more = 2 ;
        end

        % Button pushed function: ImageSaveButton
        function ImageSaveButtonPushed(app, event)
            
            time = datestr(now, 'yyyymmddHHMMSS');
            save_fname = ['result-',app.fname,'_',time,'.mat'] ;
            
            img_ch_green_data = app.img_ch_green_data_collection;
            img_ch_red_data = app.img_ch_red_data_collection ;
            overlapped_colocal=app.overlapped_colocal ;
            pearson_cor = app.pearson_cor ;
            
            background_setting = app.background_setting.Value;
            peak_background_fold = app.peak_background_fold.Value;
            tolerance_parameter = app.tolerance_parameter.Value ;
            
            redch_background_setting = app.redch_background_setting.Value;
            redch_peak_background_fold = app.redch_peak_background_fold.Value;
            redch_tolerance_parameter = app.redch_tolerance_parameter.Value ;
            
            save(save_fname,'img_ch_green_data','img_ch_red_data','overlapped_colocal','pearson_cor','background_setting','peak_background_fold','tolerance_parameter','redch_background_setting','redch_peak_background_fold','redch_tolerance_parameter')
            
            
        end

        % Button pushed function: SummarizeButton
        function SummarizeButtonPushed(app, event)
            save_info=dir('*.mat') ; % find files with .mat extension
            savenames = {save_info.name} ; % get file names
            sname_list = savenames ;
            sname_list
            
            final_num_cond = [];
            final_dilute_avg_int = [];
            final_avg_int_cond = [] ;
            final_sum_int_cond = [] ;
            final_size_cond = [] ;
            final_Kp = [] ;
            final_G_transfer = [] ;
            
            final_avg_int_cond_raw = [];
            final_sum_int_cond_raw = [];
            final_size_cond_raw =[];
            final_Kp_raw =[];
            final_G_transfer_raw = [];
            final_overlapped_colocal = [];
            final_pearson_cor = [] ;
            
            set_info_background_setting = [] ;
            set_info_peak_background_fold = [];
            set_info_tolerance_parameter = [] ;
      
            set_info_redch_background_setting = [] ;
            set_info_redch_peak_background_fold = [];
            set_info_redch_tolerance_parameter = [] ;
            
            for k=1:length(sname_list)
                tmp_name = sname_list{k};
                tmp_dat_all = load(tmp_name);
                tmp_dat_all 
                tmp_dat = tmp_dat_all.img_ch_green_data;
                
                tmp_dat
                final_num_cond(end+1) = tmp_dat.num_cond;
                final_dilute_avg_int(end+1) = tmp_dat.dilute_average_int;
                final_avg_int_cond = [final_avg_int_cond, mean(tmp_dat.average_int_cond(:))] ;
                final_sum_int_cond = [final_sum_int_cond, mean(tmp_dat.sum_int_cond(:))] ;
                final_size_cond = [final_size_cond, mean(tmp_dat.size_cond(:))];
                final_Kp = [final_Kp, mean(tmp_dat.Kp(:))];
                final_G_transfer = [final_G_transfer, mean(tmp_dat.G_transfer(:))] ;
                final_overlapped_colocal = [final_overlapped_colocal, tmp_dat_all.overlapped_colocal];
                final_pearson_cor = [final_pearson_cor, tmp_dat_all.pearson_cor];
                
                set_info_background_setting = [set_info_background_setting, tmp_dat_all.background_setting];
                set_info_peak_background_fold = [set_info_peak_background_fold, tmp_dat_all.peak_background_fold];
                set_info_tolerance_parameter = [set_info_tolerance_parameter, tmp_dat_all.tolerance_parameter] ;
                                
                set_info_redch_background_setting = [set_info_redch_background_setting, tmp_dat_all.redch_background_setting];
                set_info_redch_peak_background_fold = [set_info_redch_peak_background_fold, tmp_dat_all.redch_peak_background_fold];
                set_info_redch_tolerance_parameter = [set_info_redch_tolerance_parameter, tmp_dat_all.redch_tolerance_parameter] ;
                
                final_avg_int_cond_raw = [final_avg_int_cond_raw, tmp_dat.average_int_cond(:).'];
                final_sum_int_cond_raw = [final_sum_int_cond_raw,tmp_dat.sum_int_cond(:).'];
                
                final_size_cond_raw=[final_size_cond_raw, tmp_dat.size_cond(:).'] ;
                final_Kp_raw = [final_Kp_raw, tmp_dat.Kp(:).'] ;
                final_G_transfer_raw=[final_G_transfer_raw,tmp_dat.G_transfer(:).'];
                
            end
            
            final_name_collect = {} ;
            for k=1:length(final_num_cond)
                tmp_name_collect = repmat({sname_list{k}},[final_num_cond(k),1]);
                final_name_collect=[final_name_collect;tmp_name_collect];
            end
            sname_collect = repmat({tmp_name}, [1,  tmp_dat.num_cond]);
            dat_collection = table(sname_list.',final_num_cond.',final_dilute_avg_int.',final_avg_int_cond.',final_sum_int_cond.',final_size_cond.',final_Kp.',final_G_transfer.',final_overlapped_colocal.',final_pearson_cor.',set_info_background_setting.',set_info_peak_background_fold.',set_info_tolerance_parameter.',set_info_redch_background_setting.',set_info_redch_peak_background_fold.',set_info_redch_tolerance_parameter.');
            dat_collection.Properties.VariableNames = {'Fname','numCond','AvgDilute_int','AvgInt','SumInt','Size','Kp','Gtransfer','Overlapped_Colocal','Pearson_cor','Greench_Background_setting','Greench_Peak_Background_fold','Greench_Tolerance_parameter','Redch_Background_setting','Redch_Peak_Background_fold','Redch_Tolerance_parameter'};
            
            dat_collection_raw = table(final_name_collect,final_avg_int_cond_raw.',final_sum_int_cond_raw.',final_size_cond_raw.',final_Kp_raw.',final_G_transfer_raw.');
            dat_collection_raw.Properties.VariableNames = {'Fname','AvgInt','SumInt','Size','Kp','Gtransfer'};
            writetable(dat_collection,'summarized.txt', 'Delimiter', 'tab');
            writetable(dat_collection_raw,'summarized_raw.txt', 'Delimiter', 'tab');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileNameListBoxLabel = uilabel(app.UIFigure);
            app.FileNameListBoxLabel.Text = 'File Name';
            app.FileNameListBox = uilistbox(app.UIFigure);
            app.FileNameListBox.Items = {'-'};
            app.FileNameListBox.Value = '-';
            app.PathselectButton = uibutton(app.UIFigure, 'push');
            app.PathselectButton.ButtonPushedFcn = createCallbackFcn(app, @PathselectButtonPushed, true);
            app.PathselectButton.Text = 'Path select';
            app.FolderpathTextAreaLabel = uilabel(app.UIFigure);
            app.FolderpathTextAreaLabel.Text = 'Folder path';
            app.FolderpathTextArea = uitextarea(app.UIFigure);
            app.FolderpathTextArea.ValueChangedFcn = createCallbackFcn(app, @FolderpathTextAreaValueChanged, true);
            app.FolderpathTextArea.Value = {'C:'};
            app.FileSelectButton = uibutton(app.UIFigure, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButtonPushed, true);
            app.FileSelectButton.Text = 'File Select';
            app.ROISelectButton = uibutton(app.UIFigure, 'push');
            app.ROISelectButton.ButtonPushedFcn = createCallbackFcn(app, @ROISelectButtonPushed, true);
            app.ROISelectButton.Text = 'ROI Select';
            app.ImageSaveButton = uibutton(app.UIFigure, 'push');
            app.ImageSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSaveButtonPushed, true);
            app.ImageSaveButton.Text = 'Image Save';
            app.BackgroundsettingLabel = uilabel(app.UIFigure);
            app.BackgroundsettingLabel.Text = 'Background setting:';
            app.background_setting = uieditfield(app.UIFigure, 'numeric');
            app.background_setting.Value = 1;
            app.ToleranceparameterLabel = uilabel(app.UIFigure);
            app.ToleranceparameterLabel.Text = 'Tolerance parameter: ';
            app.tolerance_parameter = uieditfield(app.UIFigure, 'numeric');
            app.tolerance_parameter.Value = 1.5;
            app.ForCOLOCALUpdated250908byTaeGyunKimLabel = uilabel(app.UIFigure);
            app.ForCOLOCALUpdated250908byTaeGyunKimLabel.Text = 'For COLOCAL : Updated 250908 by Tae Gyun Kim';
            app.SummarizeButton = uibutton(app.UIFigure, 'push');
            app.SummarizeButton.ButtonPushedFcn = createCallbackFcn(app, @SummarizeButtonPushed, true);
            app.SummarizeButton.Text = 'Summarize';
            app.PeakbackgroundEditFieldLabel = uilabel(app.UIFigure);
            app.PeakbackgroundEditFieldLabel.Text = 'Peak/background :';
            app.peak_background_fold = uieditfield(app.UIFigure, 'numeric');
            app.peak_background_fold.Value = 2;
            app.GreenchLabel = uilabel(app.UIFigure);
            app.GreenchLabel.Text = 'Green ch';
            app.RedchLabel = uilabel(app.UIFigure);
            app.RedchLabel.Text = 'Red ch';
            app.redch_background_setting = uieditfield(app.UIFigure, 'numeric');
            app.redch_background_setting.Value = 1;
            app.redch_peak_background_fold = uieditfield(app.UIFigure, 'numeric');
            app.redch_peak_background_fold.Value = 2;
            app.redch_tolerance_parameter = uieditfield(app.UIFigure, 'numeric');
            app.redch_tolerance_parameter.Value = 1.5;
            app.DonotcopypasteorsharethiscodewithanyonewithoutpermissionLabel = uilabel(app.UIFigure);
            app.DonotcopypasteorsharethiscodewithanyonewithoutpermissionLabel.Text = 'Do not copy & paste or share this code with anyone without permission.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = condensate_analyzer_confocal_COLOCAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end